classdef ChessRobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        SetFenPositionButton  matlab.ui.control.Button
        EngineMoveDoneButton  matlab.ui.control.Button
        LogTextArea           matlab.ui.control.TextArea
        Label                 matlab.ui.control.Label
        MovesTable            matlab.ui.control.Table
        TurnLabel             matlab.ui.control.Label
        ConnLamp              matlab.ui.control.Lamp
        HumanColorDropDown    matlab.ui.control.DropDown
        DepthDropDown         matlab.ui.control.DropDown
        ReadyButton           matlab.ui.control.Button
        NewGameButton         matlab.ui.control.Button
        ConnectButton         matlab.ui.control.Button
        StartServerButton     matlab.ui.control.Button
        BoardAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tcp = []              % will hold tcpclient after Connect
        RxTimer = []          % will hold timer after connect
        RxBuffer string = ""
        Perspective string = "white"
        GameOver logical = false
        MustPressReady logical = false
        PendingFen string = ""
        InRecovery logical = false
        MoveNo double = 1
        PendingWhiteMove string = ""
        LastFen string = ""
        LastLegalFen string = ""
    end
    
    methods (Access = private)

        function log(app, s)
            t = string(datetime("now","Format","HH:mm:ss"));
            app.LogTextArea.Value = [app.LogTextArea.Value; sprintf("[%s] %s", t, s)];
            drawnow limitrate;
        end
    
        function sendCmd(app, cmd)
            if isempty(app.Tcp)
                app.log("TX failed (not connected): " + cmd);
                return;
            end
            write(app.Tcp, uint8([char(cmd), newline]));
            app.log("TX " + cmd);
        end
    
        function startRxTimer(app)
        
            % Stop/delete old timer safely
            try
                if isa(app.RxTimer, "timer") && isvalid(app.RxTimer)
                    stop(app.RxTimer);
                    delete(app.RxTimer);
                end
            catch
                % ignore cleanup errors
            end
            app.RxTimer = [];
        
            % Create new timer
            app.RxTimer = timer( ...
                "ExecutionMode","fixedSpacing", ...
                "Period",0.05, ...
                "TimerFcn",@(~,~)app.onRxTick(), ...
                "ErrorFcn",@(~,e)app.onTimerError(e) );
        
            start(app.RxTimer);
        end
    
        function onRxTick(app)
            try
                if isempty(app.Tcp)
                    return;
                end
        
                n = app.Tcp.NumBytesAvailable;
                if n <= 0
                    return;
                end
        
                data = char(read(app.Tcp, n, "uint8"));
                app.RxBuffer = app.RxBuffer + string(data);
        
                % Split on newline; keep last partial line in buffer
                parts = split(app.RxBuffer, newline);
                app.RxBuffer = parts(end);
        
                for i = 1:numel(parts)-1
                    line = strtrim(parts(i));
                    if line == ""
                        continue;
                    end
                    app.handleMessage(line);
                end
            catch ex
                app.log("onRxTick exception: " + string(ex.message));
                for k = 1:min(5, numel(ex.stack))
                    app.log("  at " + string(ex.stack(k).name) + " (line " + string(ex.stack(k).line) + ")");
                end
                try, stop(app.RxTimer); end
            end
        end

        function onTimerError(app, e)
            % Robust across MATLAB versions (no e.Data.Message assumption)
            try
                if isprop(e, "Data")
                    app.log("Timer error: " + string(e.Data));
                else
                    app.log("Timer error (no Data): " + string(e));
                end
            catch ex
                app.log("Timer error (formatting failed): " + string(ex.message));
            end
        end

        function restartGame(app)
            app.GameOver = false;
            app.TurnLabel.Text = "Turn: ?";
            % If you have other UI elements, reset them here:
            % app.MoveListTextArea.Value = {};
            % app.EngineMoveDoneButton.Enable = "off";
        
            app.sendCmd("NEW_GAME");
            app.sendCmd("GET_FEN");
        end
        
        function shutdownAndClose(app)
            % stop timer
            try
                if isa(app.RxTimer, "timer") && isvalid(app.RxTimer)
                    stop(app.RxTimer);
                    delete(app.RxTimer);
                end
            catch
            end
            app.RxTimer = [];
        
            % close tcp
            try
                if ~isempty(app.Tcp)
                    clear app.Tcp;   % closes tcpclient
                end
            catch
            end
            app.Tcp = [];
        
            % close UI
            try
                delete(app.UIFigure);
            catch
            end
        end

        function ok = isValidFenBasic(app, fen)
            % Very basic FEN check: "<board> <turn> <castling> <ep> <halfmove> <fullmove>"
            try
                parts = split(strtrim(string(fen)), " ");
                if numel(parts) ~= 6
                    ok = false; return;
                end
        
                placement = char(parts(1));
                turn = parts(2);
                castling = parts(3);
                ep = parts(4);
                halfmove = parts(5);
                fullmove = parts(6);
        
                if ~(turn == "w" || turn == "b")
                    ok = false; return;
                end
        
                if castling ~= "-" && ~all(ismember(char(castling), 'KQkq'))
                    ok = false; return;
                end
        
                if ep ~= "-"
                    epc = char(ep);
                    if numel(epc) ~= 2
                        ok = false; return;
                    end
                    if ~(epc(1) >= 'a' && epc(1) <= 'h' && epc(2) >= '1' && epc(2) <= '8')
                        ok = false; return;
                    end
                end
        
                if isnan(str2double(halfmove)) || isnan(str2double(fullmove))
                    ok = false; return;
                end
        
                ranks = strsplit(placement, '/');
                if numel(ranks) ~= 8
                    ok = false; return;
                end
        
                validPieces = 'PNBRQKpnbrqk';
                for i = 1:8
                    row = ranks{i};
                    fileCount = 0;
                    for j = 1:numel(row)
                        ch = row(j);
                        if ch >= '1' && ch <= '8'
                            fileCount = fileCount + (double(ch) - double('0'));
                        elseif any(validPieces == ch)
                            fileCount = fileCount + 1;
                        else
                            ok = false; return;
                        end
                    end
                    if fileCount ~= 8
                        ok = false; return;
                    end
                end
        
                ok = true;
            catch
                ok = false;
            end
        end

        function appendMoveToTable(app, side, uciMove)
            % side: "w" or "b"
            data = app.MovesTable.Data;
            if isempty(data)
                data = cell(0,3);
            end
        
            if side == "w"
                % Start a new row
                data(end+1, :) = {app.MoveNo, char(uciMove), ''};
                app.PendingWhiteMove = uciMove;
            else
                % Fill black move in the last row (or create if missing)
                if size(data,1) == 0
                    data(end+1,:) = {app.MoveNo, '', char(uciMove)};
                else
                    data{end, 3} = char(uciMove);
                end
                app.MoveNo = app.MoveNo + 1;
                app.PendingWhiteMove = "";
            end
        
            app.MovesTable.Data = data;
        end

        function handleMessage(app, msg)
            if app.InRecovery
                if startsWith(msg, "FEN ")
                    fen = extractAfter(msg, 4);
                    drawBoardFromFen(app.BoardAxes, fen, app.Perspective);
                elseif startsWith(msg, "ERROR") || startsWith(msg, "ILLEGAL_MOVE") || startsWith(msg, "HUMAN_MOVE")
                    % ignore
                end
                return;
            end
            % Ignore "Illegal move" notifications while user is setting up pieces
            if app.MustPressReady && startsWith(msg, "ERROR Illegal move:", "IgnoreCase", true)
                return;
            end
        
            % (Optional) also ignore during recovery to avoid log spam
            if app.InRecovery && startsWith(msg, "ERROR Illegal move:", "IgnoreCase", true)
                return;
            end
        
            app.log("RX " + msg);

            if startsWith(msg, "READY_OK")
                app.sendCmd("GET_FEN");
    
            elseif startsWith(msg, "FEN ")
                fen = extractAfter(msg, 4);
                drawBoardFromFen(app.BoardAxes, char(fen), app.Perspective);
                app.LastFen = string(fen);
                % Treat any received FEN as the current "legal" reference position
                % (If your server ever sends illegal/intermediate FENs, we can refine this.)
                app.LastLegalFen = app.LastFen;
                side = fenSideToMove(char(fen));
                if side == "w"
                    app.TurnLabel.Text = "Turn: White";
                elseif side == "b"
                    app.TurnLabel.Text = "Turn: Black";
                else
                    app.TurnLabel.Text = "Turn: ?";
                end
                
            elseif startsWith(msg, "HUMAN_MOVE ")
                % Update move list (simple version: just append raw)
                if app.GameOver
                    return;
                end
                if app.MustPressReady
                    app.log("Ignoring move: press READY first")
                    return;
                end
                if app.InRecovery
                    return;
                end
                parts = split(msg);
                humanUci = string(parts(2)); % e.g. e2e4 or e7e8q
                side = fenSideToMove(char(app.LastFen)); % see note below
            
                app.appendMoveToTable(side, humanUci);

                app.sendCmd("GET_BEST_MOVE");
                app.sendCmd("GET_FEN");
    
            elseif startsWith(msg, "BEST_MOVE ")
                uci = strsplit(msg);
                uci = uci{2};
                % Engine just produced a move for the side to play (opposite of human color)
                engineSide = "b";
                if lower(string(app.HumanColorDropDown.Value)) == "black"
                    engineSide = "w";
                end
                
                app.appendMoveToTable(engineSide, string(uci));
                app.log("Robot should execute: " + uci);

                app.EngineMoveDoneButton.Enable = "on";
                % Here you can show a dialog / enable a "Move Done" button
    
            elseif startsWith(msg, "PROMOTION_REQUIRED ")
                choice = uiconfirm(app.UIFigure, ...
                    "Choose promotion piece:", ...
                    "Promotion", ...
                    "Options",["Queen","Rook","Bishop","Knight"], ...
                    "DefaultOption",1, ...
                    "CancelOption",1);
    
                map = containers.Map( ...
                    ["Queen","Rook","Bishop","Knight"], ...
                    ["q","r","b","n"]);
    
                app.sendCmd("PROMOTE " + map(choice));
    
            elseif startsWith(msg, "GAME_OVER ")
                app.GameOver = true;
                result = extractAfter(msg, 10);
                app.log("Game over: " + result);
            
                choice = uiconfirm(app.UIFigure, ...
                    "Game over: " + result + newline + "What do you want to do?", ...
                    "Game Over", ...
                    "Options", ["Restart","Exit"], ...
                    "DefaultOption", 1, ...
                    "CancelOption", 2);
            
                if choice == "Restart"
                    app.restartGame();
                    app.MovesTable.Data = cell(0,3);
                    app.MoveNo = 1;
                    app.PendingWhiteMove = "";
                else
                    app.shutdownAndClose();
                end
            elseif startsWith(msg, "ERROR Illegal move:", "IgnoreCase", true) || startsWith(msg, "ILLEGAL_MOVE")
                % If user is arranging pieces (Set FEN) or in recovery, ignore illegal move popups
                if app.MustPressReady || app.InRecovery
                    %app.log("Ignoring illegal-move notification (waiting for READY): " + msg);
                    return;
                end
            
                choice = uiconfirm(app.UIFigure, ...
                    "Illegal move detected." + newline + ...
                    "Press 'Return to last position' and fix the pieces, then press READY.", ...
                    "Illegal Move", ...
                    "Options", ["Return to last position","Ignore"], ...
                    "DefaultOption", 1, ...
                    "CancelOption", 2);
            
                if choice == "Return to last position"
                    app.InRecovery = true;
                    app.MustPressReady = true;
                    app.log("Recovery mode: fix pieces, then press READY to continue.");
                    % Force GUI back to last legal position immediately
                    if strlength(app.LastLegalFen) > 0
                        drawBoardFromFen(app.BoardAxes, char(app.LastLegalFen), app.Perspective);
                    
                        side = fenSideToMove(char(app.LastLegalFen));
                        if side == "w"
                            app.TurnLabel.Text = "Turn: White";
                        elseif side == "b"
                            app.TurnLabel.Text = "Turn: Black";
                        else
                            app.TurnLabel.Text = "Turn: ?";
                        end
                    end
                    app.sendCmd("GET_FEN");
                end
            elseif startsWith(msg, "ERROR")
                app.log("Server error: " + msg);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            here = fileparts(mfilename('fullpath'));
            addpath(fullfile(here, "matlab"));            
            app.ConnLamp.Color = [1 0 0];  % red initially
            app.TurnLabel.Text = "Turn: ?"; 
            app.EngineMoveDoneButton.Enable = "off";
            % --- Force fullscreen / maximized on launch ---
            try
                app.UIFigure.WindowState = "maximized";
            catch
                % Fallback for older MATLAB versions without WindowState
                try
                    set(app.UIFigure, "Units", "normalized", "Position", [0 0 1 1]);
                catch
                end
            end
        end

        % Button pushed function: StartServerButton
        function StartServerButtonPushed(app, event)
            here = fileparts(mfilename('fullpath'));
            py = fullfile(here, "brain_server.py");
        
            cmd = sprintf('start "" /min cmd /c "cd /d "%s" && python "%s""', here, py);
            app.log("Starting server: " + cmd);
            system(cmd);
        
            pause(0.5); 
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.Tcp = tcpclient("127.0.0.1", 5000, "Timeout", 5);
                app.ConnLamp.Color = [0 1 0];
                app.log("Connected.");
                app.startRxTimer();
            catch e
                app.ConnLamp.Color = [1 0 0];
                app.log("Connect failed: " + string(e.message));
            end
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            depth = app.DepthDropDown.Value;
            human = app.HumanColorDropDown.Value;
            app.Perspective = lower(string(human)); % show human at bottom
        
            app.sendCmd("CONFIG depth=" + depth + " human=" + human);
            app.sendCmd("NEW_GAME");
            app.sendCmd("GET_FEN");
            app.GameOver = false;
            app.MovesTable.Data = cell(0,3);
            app.MoveNo = 1;
            app.PendingWhiteMove = "";
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            % 1) If recovering from illegal move: resume only
            if app.InRecovery
                app.InRecovery = false;
                app.MustPressReady = false;
                app.log("Resumed after recovery.");
                app.sendCmd("READY");
                app.sendCmd("GET_FEN");
                return;
            end
        
            % 2) If we are waiting for READY after setting a FEN: start game from that position
            if app.MustPressReady
                app.log("Starting from custom FEN (NEW_GAME + SET_FEN + READY).");
        
                app.sendCmd("NEW_GAME");
                if strlength(app.PendingFen) > 0
                    app.sendCmd("DEBUG_SET_FEN " + app.PendingFen);
                end
                app.sendCmd("READY");
                app.sendCmd("GET_FEN");
        
                app.MustPressReady = false;
                return;
            end
        
            % 3) Normal case: READY should NOT reset anything
            app.log("READY pressed (no reset).");
            app.sendCmd("READY");
            app.sendCmd("GET_FEN");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if ~isempty(app.RxTimer) && isvalid(app.RxTimer)
                    stop(app.RxTimer);
                    delete(app.RxTimer);
                end
            catch
            end
        
            try
                if ~isempty(app.Tcp)
                    app.sendCmd("SHUTDOWN");
                    clear app.Tcp;
                end
            catch
            end
        
            delete(app.UIFigure);
        end

        % Button pushed function: EngineMoveDoneButton
        function EngineMoveDoneButtonPushed(app, event)
            app.EngineMoveDoneButton.Enable = "off";
            app.sendCmd("ENGINE_MOVE_DONE");
            pause(0.2);
            app.sendCmd("GET_FEN");
        end

        % Button pushed function: SetFenPositionButton
        function SetFenPositionButtonPushed(app, event)
            % Popup input for FEN (cancel-safe) + validation + send to server
        
            defaultFen = "rnbqkbnr/pppppppp/8/8/8/8/PPPPPPPP/RNBQKBNR w KQkq - 0 1";
        
            answer = inputdlg( ...
                {"Enter FEN (e.g. '... w KQkq - 0 1'):"}, ...
                "Set position (FEN)", ...
                [1 120], ...
                {char(defaultFen)}, ...
                "on");
        
            % User pressed Cancel or closed dialog
            if isempty(answer)
                app.log("Set FEN cancelled.");
                return;
            end
        
            fen = strtrim(string(answer{1}));
            if fen == ""
                uialert(app.UIFigure, "Incorrect FEN", "Set FEN");
                return;
            end
        
            % Basic FEN validation (safe, not perfect but prevents breaking)
            if ~app.isValidFenBasic(fen)
                uialert(app.UIFigure, "Incorrect FEN", "Set FEN");
                return;
            end
        app.sendCmd("DEBUG_SET_FEN " + fen);
        app.sendCmd("GET_FEN");
        
        % Require READY before continuing; store for later re-apply after NEW_GAME
        app.PendingFen = fen;
        app.MustPressReady = true;
        app.log("FEN loaded. Press READY to start (will send NEW_GAME + apply FEN + READY).");
        app.MovesTable.Data = cell(0,3);
        app.MoveNo = 1;
        app.PendingWhiteMove = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BoardAxes = uiaxes(app.GridLayout);
            app.BoardAxes.XTick = [];
            app.BoardAxes.YTick = [];
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.StartServerButton = uibutton(app.GridLayout2, 'push');
            app.StartServerButton.ButtonPushedFcn = createCallbackFcn(app, @StartServerButtonPushed, true);
            app.StartServerButton.Text = 'Start Server';
            app.ConnectButton = uibutton(app.GridLayout2, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.NewGameButton = uibutton(app.GridLayout2, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.ReadyButton = uibutton(app.GridLayout2, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'READY';
            app.DepthDropDown = uidropdown(app.GridLayout2);
            app.DepthDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.DepthDropDown.Value = '5';
            app.HumanColorDropDown = uidropdown(app.GridLayout2);
            app.HumanColorDropDown.Items = {'white', 'black'};
            app.HumanColorDropDown.Value = 'white';
            app.ConnLamp = uilamp(app.GridLayout2);
            app.TurnLabel = uilabel(app.GridLayout2);
            app.TurnLabel.Text = 'Turn: ?';
            app.MovesTable = uitable(app.GridLayout2);
            app.MovesTable.ColumnName = {'#'; 'White'; 'Black'};
            app.MovesTable.RowName = {};
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.LogTextArea = uitextarea(app.GridLayout2);
            app.LogTextArea.Editable = 'off';
            app.EngineMoveDoneButton = uibutton(app.GridLayout2, 'push');
            app.EngineMoveDoneButton.ButtonPushedFcn = createCallbackFcn(app, @EngineMoveDoneButtonPushed, true);
            app.EngineMoveDoneButton.Text = 'Engine Move Done';
            app.SetFenPositionButton = uibutton(app.GridLayout2, 'push');
            app.SetFenPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetFenPositionButtonPushed, true);
            app.SetFenPositionButton.Text = 'Set Fen Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChessRobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end